classdef Convergence_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectDisributionLabel  matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        ExponentialButton       matlab.ui.control.Button
        UniformButton           matlab.ui.control.Button
        NormalButton            matlab.ui.control.Button
        distribution            matlab.ui.control.UIAxes
        Data                    matlab.ui.control.UIAxes
        convergence             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExponentialButton
        function ExponentialButtonPushed(app, event)
        a = 1;
        b = 2000;
        [T, P, A, e_n, Fn] = convergence_exponential();
        plot(app.Data,a:1:b,P(a:1:b),a:1:1000,A(a:1:1000),a:1:b,e_n(a:1:b),'g')
        plot(app.distribution, -4:0.0001:4,Fn,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[-4 4]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'Prob. conv','a.s conv','mean sq. convergence')
        end

        % Callback function
        function GenerateButtonPushed(app, event)
    
     
        end

        % Button pushed function: NormalButton
        function NormalButtonPushed(app, event)
        a = 1;
        b = 2000;
        [T, P, A, e_n, Fn] = convergence_normal();
        plot(app.Data,a:1:b,P(a:1:b),a:1:1000,A(a:1:1000),a:1:b,e_n(a:1:b),'g')
        plot(app.distribution, -4:0.0001:4,Fn,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[-4 4]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'Prob. conv','a.s conv','mean sq. convergence')
        end

        % Button pushed function: UniformButton
        function UniformButtonPushed(app, event)
        a = 1;
        b = 2000;
        [T, P, A, e_n, Fn] = convergence_uniform();
         plot(app.Data,a:1:b,P(a:1:b),a:1:1000,A(a:1:1000),a:1:b,e_n(a:1:b),'g')
        plot(app.distribution, -4:0.0001:4,Fn,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[-4 4]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'Prob. conv','a.s conv','mean sq. convergence')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.convergence = uiaxes(app.UIFigure);
            title(app.convergence, 'M realizations')
            xlabel(app.convergence, 'N samples ')
            ylabel(app.convergence, '|X_n - X|')
            app.Data = uiaxes(app.UIFigure);
            title(app.Data, 'Convergence ')
            xlabel(app.Data, 'N samples')
            ylabel(app.Data, 'probability P_n, A ,error e_n')
            app.distribution = uiaxes(app.UIFigure);
            title(app.distribution, 'Convergence in Distribution')
            xlabel(app.distribution, 'N samples')
            ylabel(app.distribution, 'F_n')
            app.NormalButton = uibutton(app.UIFigure, 'push');
            app.NormalButton.ButtonPushedFcn = createCallbackFcn(app, @NormalButtonPushed, true);
            app.NormalButton.Text = 'Normal';
            app.UniformButton = uibutton(app.UIFigure, 'push');
            app.UniformButton.ButtonPushedFcn = createCallbackFcn(app, @UniformButtonPushed, true);
            app.UniformButton.Text = 'Uniform';
            app.ExponentialButton = uibutton(app.UIFigure, 'push');
            app.ExponentialButton.ButtonPushedFcn = createCallbackFcn(app, @ExponentialButtonPushed, true);
            app.ExponentialButton.Text = 'Exponential';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Convergence for Distributions'};
            app.SelectDisributionLabel = uilabel(app.UIFigure);
            app.SelectDisributionLabel.Text = 'Select Disribution: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Convergence_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end